classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EGYPTIANRAILWAYUIFigure         matlab.ui.Figure
        RailwayHealthCheckerButton      matlab.ui.control.Button
        RAILWAYButton                   matlab.ui.control.Button
        EGYPTIANButton                  matlab.ui.control.Button
        Button_4                        matlab.ui.control.Button
        Button_5                        matlab.ui.control.Button
        Button_3                        matlab.ui.control.Button
        Label_3                         matlab.ui.control.Label
        Hyperlink                       matlab.ui.control.Hyperlink
        Label_2                         matlab.ui.control.Label
        FunctionalityDropDown           matlab.ui.control.DropDown
        FunctionalityDropDownLabel      matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        EGYPTIANRAILWAYLabel            matlab.ui.control.Label
        Button_2                        matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        AccidentDropDown                matlab.ui.control.DropDown
        AccidentDropDownLabel           matlab.ui.control.Label
        Button_6                        matlab.ui.control.Button
        AccidentAvoidanceDropDown       matlab.ui.control.DropDown
        AccidentAvoidanceDropDownLabel  matlab.ui.control.Label
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Drop down opening function: AccidentAvoidanceDropDown
        function AccidentAvoidanceDropDownOpening(app, event)
           selectedOption = app.AccidentAvoidanceDropDown.Value;
    switch selectedOption
        case 'Ramses'
            % Call untitled8 with the corresponding value
            %untitled8(1);
            RamsesAvoid();
           %app.Label.Text=['ramses'];
        case 'Qalyoub'
            % Call untitled8 with the corresponding value
            QalyoubAvoid();
           % app.Label.Text=['qalyoub'];

        case 'Toukh'
            ToukhAvoid();
            % Call untitled8 with the corresponding value
                     %  app.Label.Text=['toukh'];

            
        case 'Assuit'
            AssuitAvoid();
            % Call untitled8 with the corresponding value
                      % app.Label.Text=['assuit'];

            
    end
        end

        % Drop down opening function: AccidentDropDown
        function AccidentDropDownOpening(app, event)
             selectedOption = app.AccidentDropDown.Value;
    switch selectedOption
        case 'Ramses'
            % Call untitled8 with the corresponding value
            %untitled8(1);
            Ramses1();
           
        case 'Qalyoub'
            % Call untitled8 with the corresponding value
            Qalyoub911();
        case 'Toukh'
            Toukh911();
            % Call untitled8 with the corresponding value
            
        case 'Assuit'
            Assuit911();
            % Call untitled8 with the corresponding value
            
    end
            
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            second_test;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            currentTime = datestr(now, 'HH:MM:SS');
    
    % Update text component with current time
    app.EGYPTIANRAILWAYLabel.Text = [ currentTime];
        end

        % Drop down opening function: FunctionalityDropDown
        function FunctionalityDropDownOpening(app, event)
             selectedOption = app.FunctionalityDropDown.Value;
    switch selectedOption
        case 'Ramses'
            % Call untitled8 with the corresponding value
            %untitled8(1);
            %Ramses1();
            app.Label.Text=['Ramses Accident Info  :']
            app.Label_2.Text=['Technology Used To Avoid Accident :']
            app.Label_3.Text=['Collision Detection System']
            app.Hyperlink.Text=['More Info About Ramses Accident'];
            app.Hyperlink.URL=['https://www.youm7.com/Tags/Index?id=1642828&tag=%d8%ad%d8%a7%d8%af%d8%ab-%d9%82%d8%b7%d8%a7%d8%b1-%d8%b1%d9%85%d8%b3%d9%8a%d8%b3']
        case 'Qalyoub'
            % Call untitled8 with the corresponding value
            %Qalyoub911();
            app.Label.Text=['Qalyoub Accident Info  :']
            app.Label_2.Text=['Technology Use :']
             app.Label_3.Text=['Track Monitoring Sensors']
            app.Hyperlink.Text=['More Info About Qalyoub Accident'];
            app.Hyperlink.URL=['https://www.youm7.com/Tags/Index?id=2738339&tag=%d8%ad%d8%a7%d8%af%d8%ab-%d9%82%d8%b7%d8%a7%d8%b1-%d9%82%d9%84%d9%8a%d9%88%d8%a8-%d8%a7%d9%84%d9%8a%d9%88%d9%85']
        case 'Toukh'
            %Toukh911();
            app.Label.Text=['Toukh Accident Info  :']
            app.Label_2.Text=['Technology Use :']
            app.Label_3.Text=['Automatic Brake System']
            app.Hyperlink.Text=['More Info About Toukh Accident'];
            app.Hyperlink.URL=['https://www.youm7.com/Tags/Index?id=2230643&tag=%d8%ad%d8%a7%d8%af%d8%ab-%d9%82%d8%b7%d8%a7%d8%b1-%d8%b7%d9%88%d8%ae']

            % Call untitled8 with the corresponding value
            
        case 'Assuit'
            app.Label.Text=['Assuit Accident Info  :']
            app.Label_2.Text=['Technology Use :']
            app.Label_3.Text=['Signal Interlocking Technology']
            app.Hyperlink.Text=['More Info About Assuit Accident'];
            app.Hyperlink.URL=['https://www.youm7.com/Tags/Index?id=817645&tag=%d8%ad%d8%a7%d8%af%d8%ab-%d8%a7%d8%b3%d9%8a%d9%88%d8%b7']
            % Call untitled8 with the corresponding value
            
    end
            
        end

        % Callback function
        function Hyperlink2Clicked(app, event)
          
    % For example, you can visualize the scenario using plot commands, or
    % you can use the scenario data for further analysis or simulations


        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            RealYScenario
            %real 2 train come on each other
            %black
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            RealTrainBus
            %real bus train
            %red
            
            
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
           BaliseBeacons;
            %real railway sector cairo alex
            %sector
           
        end

        % Button pushed function: EGYPTIANButton
        function EGYPTIANButtonPushed(app, event)
             TrainWayWithObstacle
            %simulation one way for railway sector 
            %railway
        end

        % Button pushed function: RAILWAYButton
        function RAILWAYButtonPushed(app, event)
             Complex_All
            
            %sumulation for real way sector complex more than 1 way
            %white
        end

        % Window button motion function: EGYPTIANRAILWAYUIFigure
        function EGYPTIANRAILWAYUIFigureWindowButtonMotion(app, event)

        end

        % Window scroll wheel function: EGYPTIANRAILWAYUIFigure
        function EGYPTIANRAILWAYUIFigureWindowScrollWheel(app, event)
            verticalScrollAmount = event.VerticalScrollAmount;
            verticalScrollCount = event.VerticalScrollCount;
            app.Button_4.Text="Real 2 Train movement";
            app.Button_5.Text="Real bus train movement";
            app.Button_3.Text="Real way from alex to cairo";
            app.EGYPTIANButton.Text="Simulation for movement train with obstacle";
            app.RAILWAYButton.Text="Simulation for complex slipway";

            app.Button_4.BackgroundColor=[0.8 0.8 0.8];
            app.Button_5.BackgroundColor=[0.8 0.8 0.8];
            app.Button_3.BackgroundColor=[0.8 0.8 0.8];
            app.EGYPTIANButton.BackgroundColor=[0.8 0.8 0.8];
            app.RAILWAYButton.BackgroundColor=[0.8 0.8 0.8];

        end

        % Window key press function: EGYPTIANRAILWAYUIFigure
        function EGYPTIANRAILWAYUIFigureWindowKeyPress(app, event)
            key = event.Key;
            
        end

        % Window button down function: EGYPTIANRAILWAYUIFigure
        function EGYPTIANRAILWAYUIFigureWindowButtonDown(app, event)
                        app.Button_4.Text="";
            app.Button_5.Text="";
            app.Button_3.Text="";
            app.EGYPTIANButton.Text="EGYPTIAN";
            app.RAILWAYButton.Text="RAILWAY";

            app.Button_4.BackgroundColor=[0.00,0.00,0.00];
            app.Button_5.BackgroundColor=[1 1 1];
            app.Button_3.BackgroundColor=[1.00,0.00,0.00];
            app.EGYPTIANButton.BackgroundColor=[0.8 0.8 0.8];
            app.RAILWAYButton.BackgroundColor=[1 1 1];

        end

        % Button pushed function: RailwayHealthCheckerButton
        function RailwayHealthCheckerButtonPushed(app, event)
            RailwayHealth
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.EGYPTIANRAILWAYUIFigure = uifigure('Visible', 'off');
            app.EGYPTIANRAILWAYUIFigure.Name = 'EGYPTIAN RAILWAY | سكك حديد مصر';
            app.EGYPTIANRAILWAYUIFigure.Icon = fullfile(pathToMLAPP, 'WhatsApp Image 2024-03-03 at 6.23.58 PM.jpeg');
            app.EGYPTIANRAILWAYUIFigure.WindowButtonDownFcn = createCallbackFcn(app, @EGYPTIANRAILWAYUIFigureWindowButtonDown, true);
            app.EGYPTIANRAILWAYUIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @EGYPTIANRAILWAYUIFigureWindowButtonMotion, true);
            app.EGYPTIANRAILWAYUIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @EGYPTIANRAILWAYUIFigureWindowScrollWheel, true);
            app.EGYPTIANRAILWAYUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @EGYPTIANRAILWAYUIFigureWindowKeyPress, true);
            app.AccidentAvoidanceDropDownLabel = uilabel(app.EGYPTIANRAILWAYUIFigure);
            app.AccidentAvoidanceDropDownLabel.Text = 'Accident Avoidance';
            app.AccidentAvoidanceDropDown = uidropdown(app.EGYPTIANRAILWAYUIFigure);
            app.AccidentAvoidanceDropDown.Items = {'choose country', 'Ramses', 'Qalyoub', 'Toukh', 'Assuit'};
            app.AccidentAvoidanceDropDown.DropDownOpeningFcn = createCallbackFcn(app, @AccidentAvoidanceDropDownOpening, true);
            app.AccidentAvoidanceDropDown.Placeholder = 'drop';
            app.AccidentAvoidanceDropDown.Value = 'choose country';
            app.Button_6 = uibutton(app.EGYPTIANRAILWAYUIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Icon = fullfile(pathToMLAPP, 'curve-down-arrow_left.png');
            app.Button_6.Text = '';
            app.AccidentDropDownLabel = uilabel(app.EGYPTIANRAILWAYUIFigure);
            app.AccidentDropDownLabel.Text = 'Accident';
            app.AccidentDropDown = uidropdown(app.EGYPTIANRAILWAYUIFigure);
            app.AccidentDropDown.Items = {'choose country', 'Ramses', 'Qalyoub', 'Toukh', 'Assuit'};
            app.AccidentDropDown.DropDownOpeningFcn = createCallbackFcn(app, @AccidentDropDownOpening, true);
            app.AccidentDropDown.Value = 'choose country';
            app.Image = uiimage(app.EGYPTIANRAILWAYUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'shohda.jpg');
            app.Button_2 = uibutton(app.EGYPTIANRAILWAYUIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = fullfile(pathToMLAPP, 'WhatsApp Image 2024-03-03 at 6.23.58 PM.jpeg');
            app.Button_2.Text = '';
            app.EGYPTIANRAILWAYLabel = uilabel(app.EGYPTIANRAILWAYUIFigure);
            app.EGYPTIANRAILWAYLabel.Text = {'سكك حديد مصر'; 'EGYPTIAN RAILWAY'};
            app.Label = uilabel(app.EGYPTIANRAILWAYUIFigure);
            app.Label.Text = '';
            app.FunctionalityDropDownLabel = uilabel(app.EGYPTIANRAILWAYUIFigure);
            app.FunctionalityDropDownLabel.Text = 'Functionality';
            app.FunctionalityDropDown = uidropdown(app.EGYPTIANRAILWAYUIFigure);
            app.FunctionalityDropDown.Items = {'choose country', 'Ramses', 'Qalyoub', 'Toukh', 'Assuit'};
            app.FunctionalityDropDown.DropDownOpeningFcn = createCallbackFcn(app, @FunctionalityDropDownOpening, true);
            app.FunctionalityDropDown.Value = 'choose country';
            app.Label_2 = uilabel(app.EGYPTIANRAILWAYUIFigure);
            app.Label_2.Text = '';
            app.Hyperlink = uihyperlink(app.EGYPTIANRAILWAYUIFigure);
            app.Hyperlink.Text = '';
            app.Label_3 = uilabel(app.EGYPTIANRAILWAYUIFigure);
            app.Label_3.Text = '';
            app.Button_3 = uibutton(app.EGYPTIANRAILWAYUIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '';
            app.Button_5 = uibutton(app.EGYPTIANRAILWAYUIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Icon = fullfile(pathToMLAPP, 'nesrr.png');
            app.Button_5.Text = '';
            app.Button_4 = uibutton(app.EGYPTIANRAILWAYUIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '';
            app.EGYPTIANButton = uibutton(app.EGYPTIANRAILWAYUIFigure, 'push');
            app.EGYPTIANButton.ButtonPushedFcn = createCallbackFcn(app, @EGYPTIANButtonPushed, true);
            app.EGYPTIANButton.Text = 'EGYPTIAN ';
            app.RAILWAYButton = uibutton(app.EGYPTIANRAILWAYUIFigure, 'push');
            app.RAILWAYButton.ButtonPushedFcn = createCallbackFcn(app, @RAILWAYButtonPushed, true);
            app.RAILWAYButton.Text = 'RAILWAY';
            app.RailwayHealthCheckerButton = uibutton(app.EGYPTIANRAILWAYUIFigure, 'push');
            app.RailwayHealthCheckerButton.ButtonPushedFcn = createCallbackFcn(app, @RailwayHealthCheckerButtonPushed, true);
            app.RailwayHealthCheckerButton.Text = 'Railway Health Checker';
            app.ContextMenu = uicontextmenu(app.EGYPTIANRAILWAYUIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.EGYPTIANRAILWAYUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EGYPTIANRAILWAYUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EGYPTIANRAILWAYUIFigure)
        end
    end
end