classdef second_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EGYPTIANRAILWAYUIFigure      matlab.ui.Figure
        Button2                      matlab.ui.control.Button
        Button_3                     matlab.ui.control.Button
        Button_2                     matlab.ui.control.Button
        Button_4                     matlab.ui.control.Button
        Label_3                      matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        Label_4                      matlab.ui.control.Label
        TrainTimeTableDropDown       matlab.ui.control.DropDown
        TrainTimeTableDropDownLabel  matlab.ui.control.Label
        EGYPTIANRAILWAYLabel         matlab.ui.control.Label
        TalgoTrainDropDown           matlab.ui.control.DropDown
        TalgoTrainDropDownLabel      matlab.ui.control.Label
        NormalTrainDropDown          matlab.ui.control.DropDown
        NormalTrainDropDownLabel     matlab.ui.control.Label
        Button                       matlab.ui.control.Button
        Image                        matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Drop down opening function: NormalTrainDropDown
        function NormalTrainDropDownOpening(app, event)
            selectedOption = app.NormalTrainDropDown.Value;
    switch selectedOption
        case 'cairo , Alexandria'
            % Call untitled8 with the corresponding value
            DrawMap(1,2,1,2);
            %untitled8(1);
        case 'Cairo , Luxor'
            % Call untitled8 with the corresponding value
                        DrawMap(1,2,2,2);

            %untitled8(2);
        case 'Luxor , Aswan'
            % Call untitled8 with the corresponding value
                        DrawMap(1,2,3,2);

           % untitled8(3);
        case 'Marsa Matrouh , Alexandria'
            % Call untitled8 with the corresponding value
                        DrawMap(1,2,4,2);
             case 'Alexandria , Luxor'
            % Call untitled8 with the corresponding value
                        DrawMap(1,1,1,2);

            %untitled8(4);
            case 'Cairo , Aswan'
            % Call untitled8 with the corresponding value
                        DrawMap(1,2,3);
    end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
      currentTime = datestr(now, 'HH:MM:SS');
    
    % Update text component with current time
    app.EGYPTIANRAILWAYLabel.Text = [ currentTime];
        end

        % Drop down opening function: TalgoTrainDropDown
        function TalgoTrainDropDownOpening(app, event)
                     selectedOption = app.TalgoTrainDropDown.Value;
    switch selectedOption
        case 'cairo , Alexandria'
            % Call untitled8 with the corresponding value
            DrawMap(2,2,1,2);
            %untitled8(1);
        case 'Cairo , Luxor'
            % Call untitled8 with the corresponding value
                        DrawMap(2,2,2,2);

            %untitled8(2);
        case 'Luxor , Aswan'
            % Call untitled8 with the corresponding value
                        DrawMap(2,2,3,2);

           % untitled8(3);
        case 'Marsa Matrouh , Alexandria'
            % Call untitled8 with the corresponding value
                        DrawMap(2,2,4,2);
             case 'Alexandria , Luxor'
            % Call untitled8 with the corresponding value
                        DrawMap(2,1,1,2);

            %untitled8(4);
            case 'Cairo , Aswan'
            % Call untitled8 with the corresponding value
                        DrawMap(2,1,2,3);
    end
            
        end

        % Value changed function: TrainTimeTableDropDown
        function TrainTimeTableDropDownValueChanged(app, event)
            selectedOption = app.TrainTimeTableDropDown.Value;
            switch selectedOption
        case 'cairo , Alexandria'
            % Call untitled8 with the corresponding value
            app.Label_4.Text=['Alexandria -> Cairo 8:15 AM' ];
             app.Label_2.Text=['Alexandria -> Cairo 11:30 AM'];
              app.Label_3.Text=['Alexandria -> Cairo 01:00 PM'];
            %untitled8(1);
        case 'Cairo , Luxor'
            % Call untitled8 with the corresponding value
            app.Label_4.Text=['Cairo -> Luxor 01:00 PM' ];
                         app.Label_2.Text=['Cairo -> Luxor 01:00 PM' ];
                        app.Label_3.Text=['Cairo -> Luxor 05:15 PM' ];
                        

            %untitled8(2);
        case 'Luxor , Aswan'
            % Call untitled8 with the corresponding value
                        app.Label_4.Text=['Luxor -> Aswan 03:05 AM' ];
                         app.Label_2.Text=['Luxor -> Aswan 06:57 PM' ];
                        app.Label_3.Text=['Luxor -> Aswan 11:10 PM' ];

           % untitled8(3);
        case 'Marsa Matrouh , Alexandria'
            % Call untitled8 with the corresponding value
                      app.Label_4.Text=['Marsa Matrouh -> Alexandria 06:40 PM' ];
                        app.Label_2.Text=['Marsa Matrouh -> Alexandria 12:00 AM' ];
                        app.Label_3.Text=['Marsa Matrouh -> Alexandria 01:40 PM' ];
             case 'Alexandria , Luxor'
            % Call untitled8 with the corresponding value
                      app.Label_4.Text=['Alexandria -> Luxor 5:50 PM' ];
                        app.Label_2.Text=['Alexandria -> Luxor 08:00 PM' ];
                        app.Label_3.Text=['Alexandria -> Luxor 10:15 PM' ];
            %untitled8(4);
            case 'Cairo , Aswan'
            % Call untitled8 with the corresponding value
                      app.Label_4.Text=['Cairo -> Aswan 07:45 PM' ];
                        app.Label_2.Text=['Cairo -> Aswan 09:25 AM' ];
                        app.Label_3.Text=['Cairo -> Aswan 09:15 PM' ];
        end
            
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            %QalyoubAvoid();
            %Assuittttt9999();
            Complex_All;
          
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app1
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            untitled5454
        end

        % Button pushed function: Button2
        function Button2Pushed(app, event)
            hope
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.EGYPTIANRAILWAYUIFigure = uifigure('Visible', 'off');
            colormap(app.EGYPTIANRAILWAYUIFigure, 'pink');
            app.EGYPTIANRAILWAYUIFigure.Name = 'EGYPTIAN RAILWAY | سكك حديد مصر';
            app.EGYPTIANRAILWAYUIFigure.Icon = fullfile(pathToMLAPP, 'WhatsApp Image 2024-03-03 at 6.23.58 PM.jpeg');
            app.Image = uiimage(app.EGYPTIANRAILWAYUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'f.jpeg');
            app.Button = uibutton(app.EGYPTIANRAILWAYUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'WhatsApp Image 2024-03-03 at 6.23.58 PM.jpeg');
            app.Button.Text = '';
            app.NormalTrainDropDownLabel = uilabel(app.EGYPTIANRAILWAYUIFigure);
            app.NormalTrainDropDownLabel.Text = 'Normal Train';
            app.NormalTrainDropDown = uidropdown(app.EGYPTIANRAILWAYUIFigure);
            app.NormalTrainDropDown.Items = {'Choose Country', 'cairo , Alexandria', 'Cairo , Luxor', 'Luxor , Aswan', 'Marsa Matrouh , Alexandria', 'Alexandria , Luxor', 'Cairo , Aswan'};
            app.NormalTrainDropDown.DropDownOpeningFcn = createCallbackFcn(app, @NormalTrainDropDownOpening, true);
            app.NormalTrainDropDown.Value = 'Choose Country';
            app.TalgoTrainDropDownLabel = uilabel(app.EGYPTIANRAILWAYUIFigure);
            app.TalgoTrainDropDownLabel.Text = 'Talgo Train';
            app.TalgoTrainDropDown = uidropdown(app.EGYPTIANRAILWAYUIFigure);
            app.TalgoTrainDropDown.Items = {'Choose Country', 'cairo , Alexandria', 'Cairo , Luxor', 'Luxor , Aswan', 'Marsa Matrouh , Alexandria', 'Alexandria , Luxor', 'Cairo , Aswan'};
            app.TalgoTrainDropDown.DropDownOpeningFcn = createCallbackFcn(app, @TalgoTrainDropDownOpening, true);
            app.TalgoTrainDropDown.Value = 'Choose Country';
            app.EGYPTIANRAILWAYLabel = uilabel(app.EGYPTIANRAILWAYUIFigure);
            app.EGYPTIANRAILWAYLabel.Text = {'سكك حديد مصر'; 'EGYPTIAN RAILWAY'};
            app.TrainTimeTableDropDownLabel = uilabel(app.EGYPTIANRAILWAYUIFigure);
            app.TrainTimeTableDropDownLabel.Text = 'Train Time Table';
            app.TrainTimeTableDropDown = uidropdown(app.EGYPTIANRAILWAYUIFigure);
            app.TrainTimeTableDropDown.Items = {'Choose Country', 'cairo , Alexandria', 'Cairo , Luxor', 'Luxor , Aswan', 'Marsa Matrouh , Alexandria', 'Alexandria , Luxor', 'Cairo , Aswan'};
            app.TrainTimeTableDropDown.ValueChangedFcn = createCallbackFcn(app, @TrainTimeTableDropDownValueChanged, true);
            app.TrainTimeTableDropDown.Value = 'Choose Country';
            app.Label_4 = uilabel(app.EGYPTIANRAILWAYUIFigure);
            app.Label_4.Text = '';
            app.Label_2 = uilabel(app.EGYPTIANRAILWAYUIFigure);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.EGYPTIANRAILWAYUIFigure);
            app.Label_3.Text = '';
            app.Button_4 = uibutton(app.EGYPTIANRAILWAYUIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '';
            app.Button_2 = uibutton(app.EGYPTIANRAILWAYUIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = fullfile(pathToMLAPP, 'curve-down-arrow.png');
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.EGYPTIANRAILWAYUIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = fullfile(pathToMLAPP, 'nesrr.png');
            app.Button_3.Text = '';
            app.Button2 = uibutton(app.EGYPTIANRAILWAYUIFigure, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button2.Text = 'Button2';
            % Show the figure after all components are created
            app.EGYPTIANRAILWAYUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = second_test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EGYPTIANRAILWAYUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EGYPTIANRAILWAYUIFigure)
        end
    end
end